classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StanAktualizujButton      matlab.ui.control.Button
        OdomAktualizujButton      matlab.ui.control.Button
        ZaawansowaneOdomButton    matlab.ui.control.Button
        ZaawansowaneVelButton     matlab.ui.control.Button
        StanDronaTextArea         matlab.ui.control.TextArea
        StandronaTextArea_2Label  matlab.ui.control.Label
        RozlaczButton             matlab.ui.control.Button
        ZadanaPredkoscLabel       matlab.ui.control.Label
        OdometriaLabel            matlab.ui.control.Label
        ZrobZdjecieButton         matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        UstawButton               matlab.ui.control.Button
        VelUITable                matlab.ui.control.Table
        OdomUITable               matlab.ui.control.Table
        AwariaButton              matlab.ui.control.Button
        LadowanieButton           matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        PolaczButton              matlab.ui.control.Button
        PortEditField             matlab.ui.control.NumericEditField
        PortEditFieldLabel        matlab.ui.control.Label
        IPMasteraEditField        matlab.ui.control.EditField
        IPmasteraEditFieldLabel   matlab.ui.control.Label
    end

    
    % ROSowe publishery i subscribery
    properties (Access = private)
        takeoff_pub
        land_pub
        emergency_pub
        cmd_vel_pub
        
        odometry_sub
        image_raw_sub
        battery_state_sub
        wifi_state_sub
        flying_state_sub
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IPMasteraEditField.Value = '192.168.0.58';
            app.OdomUITable.Data = [0, 0, 0, 0, 0, 0];
            app.VelUITable.Data = [0, 0, 0, 0];
        end

        % Button pushed function: PolaczButton
        function PolaczButtonPushed(app, event)
            ros_connect(app.IPMasteraEditField.Value, app.PortEditField.Value)

            app.takeoff_pub = rospublisher('/bebop/takeoff', 'std_msgs/Empty');
            app.land_pub = rospublisher('/bebop/land', 'std_msgs/Empty');
            app.emergency_pub = rospublisher('/bebop/emergency', 'std_msgs/Empty');
            app.cmd_vel_pub = rospublisher('bebop/cmd_vel', 'geometry_msgs/Twist');
            
            app.odometry_sub = rossubscriber('/bebop/odom', 'nav_msgs/Odometry');
            app.image_raw_sub = rossubscriber('/bebop/image_raw', 'sensor_msgs/Image');
            %     app.battery_state_sub = rossubscriber('/bebop/states/common/CommonState/BatteryStateChanged');
            %     app.wifi_state_sub = rossubscriber('/bebop/states/common/CommonState/WifiSignalChanged');
            %     app.flying_state_sub = rossubscriber('/bebop/states/ardrone3/PilotingState/FlyingStateChanged');
    
        end

        % Button pushed function: RozlaczButton
        function RozlaczButtonPushed(app, event)
            rosshutdown
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            takeoff(app.takeoff_pub)
        end

        % Button pushed function: LadowanieButton
        function LadowanieButtonPushed(app, event)
            land(app.land_pub)
        end

        % Button pushed function: AwariaButton
        function AwariaButtonPushed(app, event)
            emergency(app.emergency_pub)
        end

        % Button pushed function: UstawButton
        function UstawButtonPushed(app, event)
            cmd_vel = cell2mat(app.VelUITable.Data);
            set_cmd_vel(app.cmd_vel_pub, cmd_vel)
        end

        % Button pushed function: ZrobZdjecieButton
        function ZrobZdjecieButtonPushed(app, event)
            img = get_image_raw(app.image_raw_sub);
            app.Image.ImageSource = img;
        end

        % Button pushed function: OdomAktualizujButton
        function OdomAktualizujButtonPushed(app, event)
            odometry = get_odometry(app.odometry_sub);
            app.OdomUITable.Data = odometry;
        end

        % Button pushed function: StanAktualizujButton
        function StanAktualizujButtonPushed(app, event)
            battery_state = get_battery_state(app.battery_state_sub);
            wifi_state = get_wifi_state(app.wifi_state_sub);
            flying_state = get_flying_state(app.flying_state_sub);

            switch flying_state
                case 0 % landed
                    flying_state_string = 'na ziemi';
                case 1 % taking off
                    flying_state_string = 'startowanie';
                case 2 % hovering
                    flying_state_string = 'unoszenie';
                case 3 % flying
                    flying_state_string = 'lot';
                case 4 %landing
                    flying_state_string = 'lądowanie';
                case 5 % emergency
                    flying_state_string = 'awaria';
                case 6 % user take off
                    flying_state_string = 'startowanie użytkownika';
                case 7 % motor ramping
                    flying_state_string = 'rozruch silników';
                case 8 % emergency landing
                    flying_state_string = 'lądowanie awaryjne';
            end

            stan1 = strcat('Poziom baterii: ', battery_state, '%');
            stan2 = strcat('Siła sygnału Wi-Fi: ', wifi_state, 'dB');
            stan3 = strcat('Stan lotu drona: ', flying_state_string);
            app.StanDronaTextArea.Value = {stan1; stan2; stan3};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IPmasteraEditFieldLabel = uilabel(app.UIFigure);
            app.IPmasteraEditFieldLabel.Text = 'IP mastera';
            app.IPMasteraEditField = uieditfield(app.UIFigure, 'text');
            app.IPMasteraEditField.Placeholder = '192.168.0.58';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 11311;
            app.PolaczButton = uibutton(app.UIFigure, 'push');
            app.PolaczButton.ButtonPushedFcn = createCallbackFcn(app, @PolaczButtonPushed, true);
            app.PolaczButton.Text = 'Połącz';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LadowanieButton = uibutton(app.UIFigure, 'push');
            app.LadowanieButton.ButtonPushedFcn = createCallbackFcn(app, @LadowanieButtonPushed, true);
            app.LadowanieButton.Text = 'Lądowanie';
            app.AwariaButton = uibutton(app.UIFigure, 'push');
            app.AwariaButton.ButtonPushedFcn = createCallbackFcn(app, @AwariaButtonPushed, true);
            app.AwariaButton.Text = 'Awaria';
            app.OdomUITable = uitable(app.UIFigure);
            app.OdomUITable.ColumnName = {'x'; 'y'; 'z'; 'alfa'; 'beta'; 'gamma'};
            app.OdomUITable.RowName = {};
            app.VelUITable = uitable(app.UIFigure);
            app.VelUITable.ColumnName = {'vx'; 'vy'; 'vz'; 'omega'};
            app.VelUITable.RowName = {};
            app.VelUITable.ColumnEditable = true;
            app.UstawButton = uibutton(app.UIFigure, 'push');
            app.UstawButton.ButtonPushedFcn = createCallbackFcn(app, @UstawButtonPushed, true);
            app.UstawButton.Text = 'Ustaw';
            app.Image = uiimage(app.UIFigure);
            app.ZrobZdjecieButton = uibutton(app.UIFigure, 'push');
            app.ZrobZdjecieButton.ButtonPushedFcn = createCallbackFcn(app, @ZrobZdjecieButtonPushed, true);
            app.ZrobZdjecieButton.Text = 'Zrób zdjęcie';
            app.OdometriaLabel = uilabel(app.UIFigure);
            app.OdometriaLabel.Text = 'Odometria';
            app.ZadanaPredkoscLabel = uilabel(app.UIFigure);
            app.ZadanaPredkoscLabel.Text = 'Zadana prędkość';
            app.RozlaczButton = uibutton(app.UIFigure, 'push');
            app.RozlaczButton.ButtonPushedFcn = createCallbackFcn(app, @RozlaczButtonPushed, true);
            app.RozlaczButton.Text = 'Rozłącz';
            app.StandronaTextArea_2Label = uilabel(app.UIFigure);
            app.StandronaTextArea_2Label.Text = 'Stan drona';
            app.StanDronaTextArea = uitextarea(app.UIFigure);
            app.StanDronaTextArea.Editable = 'off';
            app.ZaawansowaneVelButton = uibutton(app.UIFigure, 'push');
            app.ZaawansowaneVelButton.Text = 'Zaawansowane';
            app.ZaawansowaneOdomButton = uibutton(app.UIFigure, 'push');
            app.ZaawansowaneOdomButton.Text = 'Zaawansowane';
            app.OdomAktualizujButton = uibutton(app.UIFigure, 'push');
            app.OdomAktualizujButton.ButtonPushedFcn = createCallbackFcn(app, @OdomAktualizujButtonPushed, true);
            app.OdomAktualizujButton.Text = 'Aktualizuj';
            app.StanAktualizujButton = uibutton(app.UIFigure, 'push');
            app.StanAktualizujButton.ButtonPushedFcn = createCallbackFcn(app, @StanAktualizujButtonPushed, true);
            app.StanAktualizujButton.Text = 'Aktualizuj';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end